classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MedicareAnalysisAppUIFigure  matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        AreaButtonGroup              matlab.ui.container.ButtonGroup
        WestButton                   matlab.ui.control.RadioButton
        MidwestButton                matlab.ui.control.RadioButton
        NortheastButton              matlab.ui.control.RadioButton
        SouthButton                  matlab.ui.control.RadioButton
        USAButton                    matlab.ui.control.RadioButton
        SortingOptionButtonGroup     matlab.ui.container.ButtonGroup
        AscendingButton              matlab.ui.control.ToggleButton
        DescendingButton             matlab.ui.control.ToggleButton
        DropDown                     matlab.ui.control.DropDown
        GraphDisplayButton           matlab.ui.control.Button
        SaveasTextFileButton         matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        UITable                      matlab.ui.control.Table
        MedicareDataAnalysisLabel    matlab.ui.control.Label
        AverageLabel                 matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
    end

    properties (Access = public)
        area, state, val,order;
    end
    
    methods (Access = private)
        
        function pick(app)
            if app.WestButton.Value == true
                app.area = 'West';
            elseif app.MidwestButton.Value == true
                app.area = 'Midwest';
            elseif app.NortheastButton.Value == true
                app.area = 'Northeast';
            elseif app.SouthButton.Value == true
                app.area = 'South';
            else
                app.area = 'USA';
            end
        end
        
        function ord(app)
            if app.AscendingButton.Value == true
                app.order = 'Ascending';
            else
                app.order = 'Descending';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunProg(app, event)
            app.Lamp.Color = [1 0 0];
            app.Label.Text = 'Reading Dataset...';
            drawnow
            pick(app)
            [app.val, app.state] = runmain(app);
            dat = cell(length(app.state),2);
            if strcmp(app.DropDown.Value,'# of Different Treatment')
                for i = 1:length(app.state)
                    dat{i,1} = app.state(i,1:2);
                    dat{i,2} = sprintf('%5.0f', app.val(i));
                end
            else
                for i = 1:length(app.state)
                    dat{i,1} = app.state(i,1:2);
                    dat{i,2} = sprintf('$ %10.2f', app.val(i));
                end
            end
            set(app.UITable, 'Data', dat);
            app.AverageLabel.Text = sprintf('Average: $ %.2f', calcmean(app.val));
            app.Label.Text = 'Done!';
            app.Lamp.Color = [0 1 0];
        end

        % Button pushed function: GraphDisplayButton
        function GraphDisp(app, event)
            creategraph(app.state,app.val,app.DropDown.Value, app.area)
        end

        % Button pushed function: SaveasTextFileButton
        function TxtSave(app, event)
            ord(app)
            txtsave(app.state, app.val, app.DropDown.Value, app.area, app.order)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MedicareAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.MedicareAnalysisAppUIFigure.Name = 'Medicare Analysis App';
            app.Panel = uipanel(app.MedicareAnalysisAppUIFigure);
            app.AreaButtonGroup = uibuttongroup(app.Panel);
            app.AreaButtonGroup.Title = 'Area';
            app.WestButton = uiradiobutton(app.AreaButtonGroup);
            app.WestButton.Text = 'West';
            app.MidwestButton = uiradiobutton(app.AreaButtonGroup);
            app.MidwestButton.Text = 'Midwest';
            app.NortheastButton = uiradiobutton(app.AreaButtonGroup);
            app.NortheastButton.Text = 'Northeast';
            app.SouthButton = uiradiobutton(app.AreaButtonGroup);
            app.SouthButton.Text = 'South';
            app.USAButton = uiradiobutton(app.AreaButtonGroup);
            app.USAButton.Text = 'U.S.A.';
            app.USAButton.Value = true;
            app.SortingOptionButtonGroup = uibuttongroup(app.Panel);
            app.SortingOptionButtonGroup.Title = 'Sorting Option';
            app.AscendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.AscendingButton.Value = true;
            app.DescendingButton = uitogglebutton(app.SortingOptionButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Coverage Charge', 'Patient Payment', '# of Different Treatment'};
            app.DropDown.Value = 'Coverage Charge';
            app.GraphDisplayButton = uibutton(app.Panel, 'push');
            app.GraphDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @GraphDisp, true);
            app.GraphDisplayButton.Text = 'Graph Display';
            app.SaveasTextFileButton = uibutton(app.Panel, 'push');
            app.SaveasTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @TxtSave, true);
            app.SaveasTextFileButton.Text = 'Save as Text File';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunProg, true);
            app.RunButton.Text = 'Run';
            app.Panel_2 = uipanel(app.MedicareAnalysisAppUIFigure);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'State'; 'Value'};
            app.UITable.RowName = {};
            app.MedicareDataAnalysisLabel = uilabel(app.Panel_2);
            app.MedicareDataAnalysisLabel.Text = 'Medicare Data Analysis';
            app.AverageLabel = uilabel(app.MedicareAnalysisAppUIFigure);
            app.AverageLabel.Text = 'Average: ...';
            app.Label = uilabel(app.MedicareAnalysisAppUIFigure);
            app.Label.Text = '...';
            app.Lamp = uilamp(app.MedicareAnalysisAppUIFigure);
            % Show the figure after all components are created
            app.MedicareAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MedicareAnalysisAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MedicareAnalysisAppUIFigure)
        end
    end
end